classdef DSPsampleINT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Menu              matlab.ui.container.Menu
        DFTMenu           matlab.ui.container.Menu
        NMenu             matlab.ui.container.Menu
        NMenu_2           matlab.ui.container.Menu
        GridLayout        matlab.ui.container.GridLayout
        HzEditFieldLabel  matlab.ui.control.Label
        HzEditField       matlab.ui.control.NumericEditField
        TabGroup          matlab.ui.container.TabGroup
        customize         matlab.ui.container.Tab
        Function          matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        preset            matlab.ui.container.Tab
        ButtonGroup       matlab.ui.container.ButtonGroup
        cosButton         matlab.ui.control.ToggleButton
        sinButton         matlab.ui.control.ToggleButton
        NTab              matlab.ui.container.Tab
        Slider            matlab.ui.control.Slider
        GridLayout2       matlab.ui.container.GridLayout
        xrange_low        matlab.ui.control.NumericEditField
        xrange_high       matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [x,y,lk,yk]=updateplot(app)
            x = linspace(app.xrange_low.Value,app.xrange_high.Value,app.HzEditField.Value);
            assignin("base","x",x);
            str = app.Function.Value;
            f = @(x) eval(str);
            if strcmp(app.TabGroup.SelectedTab.Title,app.preset.Title)
                switch app.ButtonGroup.SelectedObject
                    %时域波形采样图像绘制
                    case app.cosButton
                        y = cos(20*pi*x).*sin(60*pi*x);
                    case app.sinButton
                        y = sin(20*pi*x).*cos(60*pi*x);
                end
            else
                y = f(x);
            end
            
            stem(app.UIAxes,x,y);
            assignin("base","y",y);
            %绘制原始频域图像（幅频特性和相频特性）
            [lk,yk] = dtft(app,y);
            %幅频特性
            plot(app.UIAxes3,lk,abs(yk));
            %相频特性
            plot(app.UIAxes2,lk,angle(yk));
        end
        
        function originplot(app)
            syms f x
            %步长和范围
            fs = 100;
            ori =  linspace(app.xrange_low.Value,app.xrange_high.Value,100);
            N = length(ori);
            figure Name '模拟信号特性'
            %绘制原始时域图像
            subplot(2,3,[1 2 4 5])
            if strcmp(app.TabGroup.SelectedTab.Title,app.preset.Title)
                switch app.ButtonGroup.SelectedObject
                    %时域波形采样图像绘制
                    case app.cosButton
                        f = @(x) cos(20*pi*x).*sin(60*pi*x);
                        str = "(cos(20\pix)*sin(60*\pix))";
                    case app.sinButton
                        f = @(x) sin(20*pi*x).*cos(60*pi*x);
                        str = "(sin(20\pix)*cos(60\pix))";
                end
                y = f(ori);
                plot(ori,y);
                xlabel('时间/s');
                ylabel('幅度');
                title(["原始图像"+str]+"时域");
                %绘制原始频域图像（幅频特性和相频特性）
                yk = fft(y);
                lk = (0:(N-1))*fs/N;
                %幅频特性
                subplot(2,3,3)
                plot(lk,abs(yk));
                xlabel('频率');
                ylabel('幅度');
                title("原始图像幅频特性");
                %相频特性
                subplot(2,3,6)
                plot(lk,angle(yk));
                xlabel('频率');
                ylabel('相角');
                title("原始图像相频特性");
            else
                str = app.Function.Value;
                f = @(x) eval(str);
                fplot(f);
                xlabel('时间/s');
                ylabel('幅度');
                title(["原始图像"+str]+"时域");
                %绘制原始频域图像（幅频特性和相频特性）
                ff = fourier(f(x));
                %幅频特性
                subplot(2,3,3)
                fplot(abs(ff));
                xlabel('频率');
                ylabel('幅度');
                title("原始图像幅频特性");
                %相频特性
                subplot(2,3,6)
                fplot(angle(ff));
                xlabel('频率');
                ylabel('相角');
                title("原始图像相频特性");
            end

        end
        
        function [lk,xk] = dft(app,xn,N)
            %dft
            if(length(xn)<N)
                xn = [xn,zeros(1,N-length(xn))];
            else
                xn = xn(1:N);
            end
            n = [0:1:N-1];                       % row vector for n
            
            k = [0:1:N-1];                       % row vecor for k
            
            WN = exp(-1i*2*pi/N);                 % Wn factor
            
            nk = n'*k;                           % creates a N by N matrix of nk values
            
            WNnk = WN .^ nk;                     % DFT matrix
            
            xk = xn * WNnk;                      % row vector for DFT coefficients
            
            lk = k/N*2 - 1;
        end
        
        function [lk,xk] = dtft(app,xn)
            
            N = length(xn);
            %取频域范围为4个周期
            
            w=[(-N):1:N]*4*pi/N;
            
            nl=0:1:(N-1);
            
            martrix = w'*nl;
            
            xk = exp(-1i*(martrix))*xn';
            
            lk = w/pi;
        end
        
        function [xn] = idft(app,Xk)
            N = length(Xk);
            n = [0:1:N-1]; % row vector for n
            k = [0:1:N-1]; % row vector for k
            WN = exp(-j*2*pi/N);
            nk = k' * n;
            WNnk = WN .^(- nk);   %IDFT matrix
            xn = (Xk*WNnk)/N;
        end
        
        function omegaplot(app,y,N)
            yfft = fft(y);
            [~,ydft] = dft(app,y,N);
            figure 
            subplot(221)
            stem(linspace(0,2,length(ydft)),abs(ydft))
            ylabel('幅度')
            title('N点离散傅立叶变换')
            subplot(222)
            stem(linspace(0,2,length(ydft)),angle(ydft))
            ylabel('相位')
            subplot(223)
            plot(linspace(0,2,length(yfft)),abs(yfft))
            ylabel('幅度')
            title('傅立叶变换X(e^{jw})')
            subplot(224)
            plot(linspace(0,2,length(yfft)),angle(yfft))
            ylabel('相位')
            %绘制IDFt，比较二者图象
            figure Name '时域图象比较'
            subplot(211)
            xn = idft(app,ydft);
            stem(xn);
            title('还原后的时域图象')
            xlim([0,length(y)]);
            subplot(212)
            stem(y);
            title('原始的时域图象')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateplot(app)
            originplot(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            updateplot(app)
            originplot(app)
        end

        % Value changed function: HzEditField
        function HzEditFieldValueChanged(app, event)
            updateplot(app)
        end

        % Value changed function: Function
        function FunctionValueChanged(app, event)
            updateplot(app)
            originplot(app)
        end

        % Value changed function: xrange_high
        function xrange_highValueChanged(app, event)
            updateplot(app)
            originplot(app)
        end

        % Value changed function: xrange_low
        function xrange_lowValueChanged(app, event)
            updateplot(app)
            originplot(app)            
        end

        % Menu selected function: NMenu
        function NMenuSelected(app, event)
            [~,y,~,~]=updateplot(app);
            omegaplot(app,y,2*length(y));
           
        end

        % Menu selected function: NMenu_2
        function NMenu_2Selected(app, event)
            [~,y,~,~]=updateplot(app);
            omegaplot(app,y,length(y)/2);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            [~,y,~,~]=updateplot(app);
            omegaplot(app,y,floor(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.DFTMenu = uimenu(app.Menu);
            app.DFTMenu.Text = '生成DFT图象';
            app.NMenu = uimenu(app.DFTMenu);
            app.NMenu.MenuSelectedFcn = createCallbackFcn(app, @NMenuSelected, true);
            app.NMenu.Text = 'N>信号长度';
            app.NMenu_2 = uimenu(app.DFTMenu);
            app.NMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NMenu_2Selected, true);
            app.NMenu_2.Text = 'N<信号长度';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HzEditFieldLabel = uilabel(app.GridLayout);
            app.HzEditFieldLabel.Text = '输入采样频率（Hz）';
            app.HzEditField = uieditfield(app.GridLayout, 'numeric');
            app.HzEditField.ValueChangedFcn = createCallbackFcn(app, @HzEditFieldValueChanged, true);
            app.HzEditField.Value = 100;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.customize = uitab(app.TabGroup);
            app.customize.Title = '自定义';
            app.Function = uieditfield(app.customize, 'text');
            app.Function.ValueChangedFcn = createCallbackFcn(app, @FunctionValueChanged, true);
            app.Function.Value = 'exp(-x)';
            app.Label = uilabel(app.customize);
            app.Label.Text = '输入原始信号表达式';
            app.preset = uitab(app.TabGroup);
            app.preset.Title = '预设';
            app.ButtonGroup = uibuttongroup(app.preset);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '选择原始信号类型';
            app.cosButton = uitogglebutton(app.ButtonGroup);
            app.cosButton.Text = '余弦';
            app.cosButton.Value = true;
            app.sinButton = uitogglebutton(app.ButtonGroup);
            app.sinButton.Text = '正弦';
            app.NTab = uitab(app.TabGroup);
            app.NTab.Title = 'N点值';
            app.Slider = uislider(app.NTab);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.xrange_low = uieditfield(app.GridLayout2, 'numeric');
            app.xrange_low.ValueChangedFcn = createCallbackFcn(app, @xrange_lowValueChanged, true);
            app.xrange_high = uieditfield(app.GridLayout2, 'numeric');
            app.xrange_high.ValueChangedFcn = createCallbackFcn(app, @xrange_highValueChanged, true);
            app.xrange_high.Value = 1;
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '原始信号定义域';
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, '幅频特性')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, '相频特性')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '时域图象')
            xlabel(app.UIAxes, '时间/s')
            ylabel(app.UIAxes, '幅度')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSPsampleINT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end